classdef assignment2a_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        OpticalCharacterRecognitionLabel  matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        ChooseActivationFunctionButtonGroup  matlab.ui.container.ButtonGroup
        SigmoidalButton          matlab.ui.control.ToggleButton
        LinearButton             matlab.ui.control.ToggleButton
        HardlimButton            matlab.ui.control.ToggleButton
        ChooseFilterOptionalButtonGroup  matlab.ui.container.ButtonGroup
        AssociativeMemoryButton  matlab.ui.control.ToggleButton
        PerceptronButton         matlab.ui.control.ToggleButton
        NonedefaultButton        matlab.ui.control.ToggleButton
        STARTButton              matlab.ui.control.Button
        ACAssignment2ALabel      matlab.ui.control.Label
    end

    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            %#ok<*NASGU> 
            classifier = "classifier_with_";
            % selected filter setting:
            if app.NonedefaultButton.Value == true
                % send the input as is with no filter
                filter_setting = "no_filter_";
            elseif app.PerceptronButton.Value == true 
                % filter input with perceptron
                filter_setting = "perceptron_";
            elseif app.AssociativeMemoryButton.Value == true 
                % filter input with assoc memory (use function directly)
                filter_setting = "associative_memory_";
            end
            disp("using filter setting: " + filter_setting)

            % selected activation setting:
            if app.HardlimButton.Value == true
                activation_setting = "hardlim";
            elseif app.LinearButton.Value == true 
                activation_setting = "purelin";
            elseif app.SigmoidalButton.Value == true 
                activation_setting = "logsig";
            end
            disp("using activation setting: " + activation_setting)


            filename =  strcat(classifier, filter_setting, activation_setting);
            disp("searching for classifier: " + filename)
            % assuming the best classifiers have been manually renamed 
            % and moved to main folder. delete name past
            % activation_setting. 2Layer has softmax after it, 1Layer has trainscg.
            classifier = load(filename);
            classifier = classifier.classifier;
            save('selected_classifier','classifier'); 
            
            % run mpaper, draw numbers, will call myclassify which loads target as "selected_classifier"
            mpaper
           

        end

        % Callback function
        function ButtonPushed(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ACAssignment2ALabel = uilabel(app.UIFigure);
            app.ACAssignment2ALabel.Text = 'AC Assignment 2A ';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.ChooseFilterOptionalButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseFilterOptionalButtonGroup.Title = 'Choose Filter (Optional):';
            app.NonedefaultButton = uitogglebutton(app.ChooseFilterOptionalButtonGroup);
            app.NonedefaultButton.Text = 'None (default)';
            app.NonedefaultButton.Value = true;
            app.PerceptronButton = uitogglebutton(app.ChooseFilterOptionalButtonGroup);
            app.PerceptronButton.Text = 'Perceptron';
            app.AssociativeMemoryButton = uitogglebutton(app.ChooseFilterOptionalButtonGroup);
            app.AssociativeMemoryButton.Text = 'Associative Memory';
            app.ChooseActivationFunctionButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseActivationFunctionButtonGroup.Title = 'Choose Activation Function:';
            app.HardlimButton = uitogglebutton(app.ChooseActivationFunctionButtonGroup);
            app.HardlimButton.Text = 'Hardlim';
            app.HardlimButton.Value = true;
            app.LinearButton = uitogglebutton(app.ChooseActivationFunctionButtonGroup);
            app.LinearButton.Text = 'Linear';
            app.SigmoidalButton = uitogglebutton(app.ChooseActivationFunctionButtonGroup);
            app.SigmoidalButton.Text = 'Sigmoidal';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'uc.png';
            app.OpticalCharacterRecognitionLabel = uilabel(app.UIFigure);
            app.OpticalCharacterRecognitionLabel.Text = 'Optical Character Recognition';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = assignment2a_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end